classdef ksim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImportMenu                    matlab.ui.container.Menu
        MapsMenu                      matlab.ui.container.Menu
        M0FileMenu                    matlab.ui.container.Menu
        T1FileMenu                    matlab.ui.container.Menu
        T2FileMenu                    matlab.ui.container.Menu
        deltaBFileMenu                matlab.ui.container.Menu
        ActivationFileMenu            matlab.ui.container.Menu
        ImportMRIStruct               matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        singlekspace                  matlab.ui.container.Menu
        TimeSerieskSpaceMenu          matlab.ui.container.Menu
        ExportMRIStruct               matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DataViewerPanel               matlab.ui.container.Panel
        DataUpscaleEditField          matlab.ui.control.NumericEditField
        DataUpscaleEditFieldLabel     matlab.ui.control.Label
        DViewButton                   matlab.ui.control.Button
        MapDropDown                   matlab.ui.control.DropDown
        MapDropDownLabel              matlab.ui.control.Label
        ViewLabel                     matlab.ui.control.Label
        SliceViewDropDown             matlab.ui.control.DropDown
        SliceLabel                    matlab.ui.control.Label
        SliceSpinner                  matlab.ui.control.Spinner
        PlotActivationCheck           matlab.ui.control.CheckBox
        MapAxes                       matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        MRIParametersTab              matlab.ui.container.Tab
        ReconstructionDropDownLabel   matlab.ui.control.Label
        ReconstructionDropDown        matlab.ui.control.DropDown
        deltaB0Check                  matlab.ui.control.CheckBox
        RelaxivityTable               matlab.ui.control.Table
        M0T1msT2msLabel               matlab.ui.control.Label
        ExportSingleKSpaceButton      matlab.ui.control.Button
        DomainDropDown_2Label         matlab.ui.control.Label
        VoxelDataDropDown_2Label      matlab.ui.control.Label
        NumberofCoilsSpinner          matlab.ui.control.Spinner
        NumberofCoilsSpinnerLabel     matlab.ui.control.Label
        FlipAngledegEditFieldLabel    matlab.ui.control.Label
        FlipAngledegEditField         matlab.ui.control.NumericEditField
        TRmsEditField                 matlab.ui.control.NumericEditField
        TRmsEditFieldLabel            matlab.ui.control.Label
        TEmsEditFieldLabel            matlab.ui.control.Label
        TEmsEditField                 matlab.ui.control.NumericEditField
        FieldStrengthTEditFieldLabel  matlab.ui.control.Label
        FieldStrengthTEditField       matlab.ui.control.NumericEditField
        AccelerationFactorLabel       matlab.ui.control.Label
        AccelerationFactorSpinner     matlab.ui.control.Spinner
        TrajectoryDropDownLabel       matlab.ui.control.Label
        TrajectoryDropDown            matlab.ui.control.DropDown
        ViewLabel_2                   matlab.ui.control.Label
        SignalEquationDropdown        matlab.ui.control.DropDown
        DomainDropDown_sample         matlab.ui.control.DropDown
        VoxelDataDropDown_sample      matlab.ui.control.DropDown
        CoilNumberSpinner_sample      matlab.ui.control.Spinner
        CoilNumberSpinner_2Label      matlab.ui.control.Label
        ActivationCheckSingleKspace   matlab.ui.control.CheckBox
        singlekSpaceButton            matlab.ui.control.Button
        singlekSpaceAxes              matlab.ui.control.UIAxes
        TaskDesignTab                 matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        Task1Tab                      matlab.ui.container.Tab
        SNREditField                  matlab.ui.control.NumericEditField
        SNREditFieldLabel             matlab.ui.control.Label
        CNREditField                  matlab.ui.control.NumericEditField
        CNREditFieldLabel             matlab.ui.control.Label
        ActivationPhase               matlab.ui.control.NumericEditField
        PhaseIncreaseLabel            matlab.ui.control.Label
        ActivationMag                 matlab.ui.control.NumericEditField
        ActivationLabel               matlab.ui.control.Label
        Task2Tab                      matlab.ui.container.Tab
        GenerateTimeSeriesDataButton  matlab.ui.control.Button
        PlotDesignButton              matlab.ui.control.Button
        TaskImagesEditField           matlab.ui.control.NumericEditField
        TaskImagesEditFieldLabel      matlab.ui.control.Label
        RestImagesEditField           matlab.ui.control.NumericEditField
        RestImagesEditFieldLabel      matlab.ui.control.Label
        EpochsEditField               matlab.ui.control.NumericEditField
        EpochsEditFieldLabel          matlab.ui.control.Label
        HRFDropDown                   matlab.ui.control.DropDown
        HRFDropDownLabel              matlab.ui.control.Label
        OtherOptionsTab               matlab.ui.container.Tab
        tobefilledinsoon8142024Label  matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        VoxelDataDropDown_2Label_2    matlab.ui.control.Label
        DomainDropDown_2Label_2       matlab.ui.control.Label
        TimeSeriesPanel               matlab.ui.container.Panel
        yEditField                    matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        ActivationLabel_2             matlab.ui.control.Label
        ActivationLamp                matlab.ui.control.Lamp
        VoxelDataDropDown             matlab.ui.control.DropDown
        VoxelDataDropDownLabel        matlab.ui.control.Label
        DomainDropDown                matlab.ui.control.DropDown
        DomainDropDownLabel           matlab.ui.control.Label
        CoilNumberSpinner             matlab.ui.control.Spinner
        CoilNumberSpinnerLabel        matlab.ui.control.Label
        TimePointSpinner              matlab.ui.control.Spinner
        TimePointSpinnerLabel         matlab.ui.control.Label
        ExportTimeSeriesButton        matlab.ui.control.Button
        VoxeltimeSeriesButton         matlab.ui.control.Button
        timeSeriesAxes                matlab.ui.control.UIAxes
        DiagnosticButton              matlab.ui.control.Button
        StatisticDropDown             matlab.ui.control.DropDown
        StatisticDropDownLabel        matlab.ui.control.Label
        statsButton                   matlab.ui.control.Button
        StatsDomainDropDown           matlab.ui.control.DropDown
        StatsVoxelDataDropDown        matlab.ui.control.DropDown
        statisticsAxes                matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        SHAKRMarquetteUniversityBodenschatzRowe2024Label  matlab.ui.control.Label
        Hyperlink                     matlab.ui.control.Hyperlink
        TextOutput                    matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        mapViewInputData; Phantom;
        M0Map; T1Map; T2Map; ActMap; deltaB;
        rec_size; kx; ky;
        kSpaceRest; kSpaceTask;
        singlekSpace; coilSensitivities;
        TE; TR; MASK; timeMap;
        MRI; kSpace;

        %fmri stuff
        design; sigma;
        kSpaceTimeSeries;
        imageSpaceTimeSeries;

        %stats stuff
        tmap; statisticsData;
    end
    
    methods (Access = private)
        
        
        function MRI = updateMRIStruct(app)
            app.MRI.SignalEquation = app.SignalEquationDropdown.Value;
            app.MRI.Trajectory = app.TrajectoryDropDown.Value;
            app.MRI.AccelerationFactor = app.AccelerationFactorSpinner.Value;
            app.MRI.FieldStrength = app.FieldStrengthTEditField.Value;
            app.MRI.EchoTime = app.TEmsEditField.Value / 1000; % convert to seconds for save
            app.MRI.RepititionTime = app.TRmsEditField.Value / 1000; % convert to seconds for save
            app.MRI.FlipAngle = app.FlipAngledegEditField.Value;
            app.MRI.NumberOfCoils = app.NumberofCoilsSpinner.Value;
            app.MRI.CoilSensitivities = app.coilSensitivities;
            app.MRI.gamma = 42.58e06; % 42 MHz/T (H nuclei gyromagnetic ratio)
            app.MRI.RelaxivityTable = app.RelaxivityTable;
            app.MRI.ReconstructionSize = length(app.Phantom.M0);
            app.MRI.IncludeB0Inhomogeneity = app.deltaB0Check.Value;
        end

        %function kSpace = updatekSpaceStruct(app)
        %    app.kSpace.kx = app.kx
        %end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%%%% ADD PATHS %%%%%
            appPath = fileparts(mfilename('fullpath'));
            addpath(appPath,'functions\');
            addpath(appPath,'reconstructionMethods\');
            addpath(appPath,'kspaceTrajectories\');
            addpath(appPath,'signalEquations\');
            addpath(appPath,'statisticsFunctions\');
            
            
            %%%%% LOAD INITIAL MAPS %%%%%
            %M0FileName = fullfile(appPath,'data/M0Map96.mat');
            %app.M0Map = load(M0FileName,'-mat');
            %T1FileName = fullfile(appPath,'data/T1Map96.mat');
            %app.T1Map = load(T1FileName);
            %T2FileName = fullfile(appPath,'data/T2Map96.mat');
            %app.T2Map = load(T2FileName);
            PhantomFileName = fullfile(appPath,'data/Brain96.mat');
            tmpBrain96 = load(PhantomFileName);
            app.Phantom = tmpBrain96.Brain96;

            ActFileName = fullfile(appPath,'data/ActMap96.mat');
            app.ActMap = load(ActFileName);

            %%%%% INITIALIZE VALUES %%%%%
            %app.TE = app.TEmsEditField.Value;
            %app.TR = app.TRmsEditField.Value;
            %app.rec_size = length(app.M0Map.M0Map);
            app.FieldStrengthTEditFieldValueChanged()
            
            
            %%%%% INITIALIZE MAP VIEWER %%%%%
            app.UpdateMapsPlot()
            %app.singlekSpaceButtonPushed() %slows startup too much, not necessary


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {918, 918, 918};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {918, 918};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {564, '1x', 347};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Menu selected function: M0FileMenu
        function M0FileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.M0FileNameEditField.Value=filename;
                app.M0Map = load(fullfile(path,filename));            
                app.MapViewSelectSelectionChanged(app);
                app.rec_size = length(app.M0Map.M0Map);
                app.SliceSpinner.Limits = [1,app.rec_size];
                %app.UpdateMapsPlot(app,event);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: T1FileMenu
        function T1FileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.T1FileNameEditField.Value=filename;
                app.T1Map = load(fullfile(path,filename));         
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event);
                %figure(app.UIFigure);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: T2FileMenu
        function T2FileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.T2FileNameEditField.Value=filename;
                app.T2Map = load(fullfile(path,filename));
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event);
                %figure(app.UIFigure);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: deltaBFileMenu
        function deltaBFileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.dBFileNameEditField.Value=filename;
                app.dBMap = load(fullfile(path,filename));
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event)
                %figure(app.UIFigure);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: ActivationFileMenu
        function ActivationFileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.ActFileNameEditField.Value=filename;
                app.ActMap = load(fullfile(path,filename));
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event)
                %figure(app.UIFigure);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: ImportMRIStruct
        function ImportMRIStructMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                app.MRI = load(fullfile(path,filename));
                app.MRI = app.MRI.MRI;
            end

            app.SignalEquationDropdown.Value = app.MRI.SignalEquation;
            app.TrajectoryDropDown.Value = app.MRI.Trajectory;
            app.AccelerationFactorSpinner.Value = app.MRI.AccelerationFactor;
            app.FieldStrengthTEditField.Value = app.MRI.FieldStrength;
            app.TEmsEditField.Value = app.MRI.EchoTime * 1000; % converting to ms for shaker
            app.TRmsEditField.Value = app.MRI.RepititionTime * 1000; % converting to ms for shaker
            app.FlipAngledegEditField.Value = app.MRI.FlipAngle;
            app.NumberofCoilsSpinner.Value = app.MRI.NumberOfCoils;
            app.RelaxivityTable = app.MRI.RelaxivityTable;
            app.deltaB0Check.Value = app.MRI.IncludeB0Inhomogeneity;


            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Callback function: MapAxes, MapDropDown, PlotActivationCheck, 
        % ...and 2 other components
        function UpdateMapsPlot(app, event)
            % select the correct map
            mapVal = app.MapDropDown.Value;
            if mapVal == 1
                data = app.Phantom.M0;
            elseif mapVal == 2
                data = app.Phantom.T1;
            elseif mapVal == 3
                data = app.Phantom.T2;
            elseif mapVal == 4
                data = app.Phantom.deltaB;
            end

            app.mapViewInputData = data;

            % get slice number and view angle
            sliceN = app.SliceSpinner.Value;
            sliceView = str2double(app.SliceViewDropDown.Value);
            if sliceView == 1 % axial
                data = squeeze(data(:,:,sliceN));
            elseif sliceView == 2 % sagital
                data = rot90(squeeze(data(:,sliceN,:)));
            elseif sliceView == 3 % coronal
                data = rot90(squeeze(data(sliceN,:,:)));
            end


            % plot data
            if mapVal == 1 % proton density
                imagesc(app.MapAxes,data,[0 1]);
                colormap(app.MapAxes,'gray');
            elseif mapVal == 2 % t 1
                imagesc(app.MapAxes,data,[0 4]);
                colormap(app.MapAxes,'jet')
            elseif mapVal == 3 % t2
                imagesc(app.MapAxes,data,[0 3]);
                colormap(app.MapAxes,'jet')
            elseif mapVal == 4 % delta B
                imagesc(app.MapAxes,data,[min(data,[],'all'),max(data,[],'all')]);
                colormap(app.MapAxes,'jet')
            end
            colorbar(app.MapAxes);
            
            % plot activation
            if app.PlotActivationCheck.Value == 1
                hold(app.MapAxes, 'on')
                imagesc(app.MapAxes,app.ActMap.ActMap,'AlphaData',0.75)
                hold(app.MapAxes, 'off')
            end
        end

        % Button pushed function: DViewButton
        function threeDViewButtonPushed(app, event)
            data = app.mapViewInputData;
            
            sliceN = app.SliceSpinner.Value;
            sliceView = str2double(app.SliceViewDropDown.Value);
            if sliceView == 1 % axial
                data = volshow(data(:,:,1:sliceN),RenderingStyle="CinematicRendering");
            elseif sliceView == 2 % sagital
                data = volshow(data(:,1:sliceN,:),RenderingStyle="CinematicRendering");
            elseif sliceView == 3 % coronal
                data = volshow(data(1:sliceN,:,:),RenderingStyle="CinematicRendering");
            end
        end

        % Drop down opening function: SignalEquationDropdown
        function SignalEquationDropdownDropDownOpening(app, event)
            signalEquationFiles = dir(fullfile('signalEquations/', '*.m'));
            app.SignalEquationDropdown.Items =  {signalEquationFiles.name}';
        end

        % Value changed function: SignalEquationDropdown
        function SignalEquationDropdownValueChanged(app, event)
            value = app.SignalEquationDropdown.Value;
            if (strcmp(value,'InversionRecovery_SigEq.m') || strcmp(value,'SpinEcho_SigEq.m'))
                app.FlipAngledegEditField.Value = 90;
                app.FlipAngledegEditField.Editable = 'off';
            else
                app.FlipAngledegEditField.Editable = 'on';
            end
        end

        % Drop down opening function: TrajectoryDropDown
        function TrajectoryDropDownOpening(app, event)
            trajectoryFiles = dir(fullfile('kspaceTrajectories/', '*.m'));
            app.TrajectoryDropDown.Items =  {trajectoryFiles.name}';
        end

        % Value changed function: FieldStrengthTEditField
        function FieldStrengthTEditFieldValueChanged(app, event)
            gm=[0.71,832,60]';
            wm=[.83,1331,60]';
            csf=[1,4000,2200]';
            M=[gm wm csf];
            app.RelaxivityTable.Data=M;
        end

        % Drop down opening function: ReconstructionDropDown
        function ReconstructionDropDownOpening(app, event)
            reconstructionFiles = dir(fullfile('reconstructionMethods/', '*.m'));
            app.ReconstructionDropDown.Items =  {reconstructionFiles.name}';
        end

        % Button pushed function: singlekSpaceButton
        function singlekSpaceButtonPushed(app, event)

            updateMRIStruct(app);

            M0Map = app.Phantom.M0;
            T1Map = app.Phantom.T1;
            T2Map = app.Phantom.T2;
            deltaB = app.Phantom.deltaB;
            rec_size = app.MRI.ReconstructionSize;

            sliceN = app.SliceSpinner.Value;
            sliceView = str2double(app.SliceViewDropDown.Value);

            nCoils = app.NumberofCoilsSpinner.Value;
            nA = app.AccelerationFactorSpinner.Value;


            if sliceView == 1 % axial
                M0Map = squeeze(M0Map(:,:,sliceN));
                T1Map = squeeze(T1Map(:,:,sliceN));
                T2Map = squeeze(T2Map(:,:,sliceN));
                deltaB = squeeze(deltaB(:,:,sliceN));
            elseif sliceView == 2 % sagital
                M0Map = rot90(squeeze(M0Map(:,sliceN,:)));
                T1Map = rot90(squeeze(T1Map(:,sliceN,:)));
                T2Map = rot90(squeeze(T2Map(:,sliceN,:)));
                deltaB = rot90(squeeze(deltaB(:,sliceN,:)));
            elseif sliceView == 3 % coronal
                M0Map = rot90(squeeze(M0Map(sliceN,:,:)));
                T1Map = rot90(squeeze(T1Map(sliceN,:,:)));
                T2Map = rot90(squeeze(T2Map(sliceN,:,:)));
                deltaB = rot90(squeeze(deltaB(sliceN,:,:)));
            end

            app.MASK = (M0Map./max(M0Map,[],'all')) > (1/10);


            % calculate k-space coordinate based on user-defined trajectory function
            trajectory = app.TrajectoryDropDown.Value;
            [app.kSpace.kX, app.kSpace.kY, app.kSpace.timeMap] = eval(strcat(trajectory(1:end-2),'(app.MRI)'));


            if strcmp(trajectory,'Cartesian_kspace.m')
                %[app.kSpace.kX, app.kSpace.kY, app.kSpace.timeMap] = Cartesian_kspace(app.MRI);
                app.singlekSpaceAxes.DataAspectRatio = [1 app.AccelerationFactorSpinner.Value 1];
            elseif strcmp(trajectory,'Radial_kspace.m')
                %[app.kSpace.kX, app.kSpace.kY, app.kSpace.timeMap] = Radial_kspace(app.MRI);
                app.singlekSpaceAxes.DataAspectRatio = [app.AccelerationFactorSpinner.Value 1 1];
            end


            nkx = length(app.kSpace.kX);
            nky = length(app.kSpace.kY);

            % fmri bet0 and beta1 stuff
            beta1 = app.ActivationMag.Value*max(max(abs(M0Map)));
            app.sigma = 1/sqrt(nkx*nky*(beta1/app.CNREditField.Value)^2);
            %app.sigma = 1/sqrt(nkx*nky*(1/app.SNREditField.Value)^2); % in (...)^2, ... is the image space sigma
            %beta1 = app.CNREditField.Value * (1/app.SNREditField.Value);
            M0MapTask = (M0Map + app.ActMap.ActMap*beta1).*exp(sqrt(-1)*app.ActMap.ActMap*(pi/180*app.ActivationPhase.Value));


            if app.MRI.NumberOfCoils > 1
                app.coilSensitivities = generate_coils(app.MRI.ReconstructionSize,app.MRI.ReconstructionSize,app.MRI.NumberOfCoils,0);
            else
                app.coilSensitivities = ones(size(M0Map));
            end
            coilSensitivities=app.coilSensitivities;
            

            %app.kSpaceRest = app.kSpaceRest + app.sigma*randn(nky,nkx) + app.sigma*randn(nky,nkx)*sqrt(-1);


            app.timeMap=app.MRI.EchoTime; % temporary to ignore actual time map 8/12/2024
            updateMRIStruct(app);


            % generate kspace based on user defined signal equation
            signalEquation = app.SignalEquationDropdown.Value;
            app.kSpaceRest = eval(strcat(signalEquation(1:end-2),'(M0Map,T1Map,T2Map,deltaB,app.kSpace,app.MRI)'));
            app.kSpaceTask = eval(strcat(signalEquation(1:end-2),'(M0MapTask,T1Map,T2Map,deltaB,app.kSpace,app.MRI)'));


            app.updateSingleKspacePlot()
            

            app.TextOutput.Value = strjoin(['Generated kSpace at',string(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss'))]);
     
       
            if trajectory == 1
                %app.reconstructButtonPushed()
                app.TextOutput.Value = strjoin(['Reconstructed Images at',string(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss'))]);
            else
                % add this 12/05/2023 vvvvv 
                % clear axes
            end

            
        end

        % Callback function: ActivationCheckSingleKspace, 
        % ...and 4 other components
        function updateSingleKspacePlot(app, event)
            voxelData = str2double(app.VoxelDataDropDown_sample.Value);
            domain = str2double(app.DomainDropDown_sample.Value);
            coilN = app.CoilNumberSpinner_sample.Value;


            if app.ActivationCheckSingleKspace.Value == 0
                app.singlekSpace=app.kSpaceRest;
            elseif app.ActivationCheckSingleKspace.Value == 1
                app.singlekSpace=app.kSpaceTask;
            end

            if domain == 1 % k space
                if voxelData == 1 % magnitude
                    imagesc(app.singlekSpaceAxes,log10(abs(app.singlekSpace(:,:,coilN))+1))
                elseif voxelData == 2 % phase
                    imagesc(app.singlekSpaceAxes,angle(app.singlekSpace(:,:,coilN)))
                elseif voxelData == 3 % real
                    imagesc(app.singlekSpaceAxes,real(app.singlekSpace(:,:,coilN)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.singlekSpaceAxes,imag(app.singlekSpace(:,:,coilN)))
                elseif voxelData == 5 % coil avg
                    imagesc(app.singlekSpaceAxes,mean(abs(app.singlekSpace),3))
                end
            elseif domain == 2 % image space
                if voxelData == 1 % magnitude
                    imagesc(app.singlekSpaceAxes,abs(ifft2(fftshift(app.singlekSpace(:,:,coilN)))))
                elseif voxelData == 2 % phase
                    imagesc(app.singlekSpaceAxes,angle(ifft2(fftshift(app.singlekSpace(:,:,coilN)))))
                elseif voxelData == 3 % real
                    imagesc(app.singlekSpaceAxes,real(ifft2(fftshift(app.singlekSpace(:,:,coilN)))))
                elseif voxelData == 4 % imaginary
                    imagesc(app.singlekSpaceAxes,imag(ifft2(fftshift(app.singlekSpace(:,:,coilN)))))
                elseif voxelData == 5 % coil avg
                    imagesc(app.singlekSpaceAxes,mean(abs(ifft2(fftshift(app.singlekSpace))),3))
                end
            elseif domain == 3 % coils
                if voxelData == 1 % magnitude
                    imagesc(app.singlekSpaceAxes,abs(app.coilSensitivities(:,:,coilN)))
                elseif voxelData == 2 % phase
                    imagesc(app.singlekSpaceAxes,angle(app.coilSensitivities(:,:,coilN)))
                elseif voxelData == 3 % real
                    imagesc(app.singlekSpaceAxes,real(app.coilSensitivities(:,:,coilN)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.singlekSpaceAxes,imag(app.coilSensitivities(:,:,coilN)))
                elseif voxelData == 5 % coil avg
                    imagesc(app.singlekSpaceAxes,mean(abs(app.coilSensitivities),3))
                end
            end

            app.TextOutput.Value = 'changed view';
        end

        % Button pushed function: PlotDesignButton
        function PlotDesignButtonPushed(app, event)
            epochs = app.EpochsEditField.Value;
            restPerEpoch = app.RestImagesEditField.Value;
            taskPerEpoch = app.TaskImagesEditField.Value;
            app.design = repmat([zeros(restPerEpoch,1);ones(taskPerEpoch,1)],epochs,1);

            fTaskDesign = figure('Name','Task Design');
            plot(app.design,'black')
            title('Task Design')
        end

        % Button pushed function: GenerateTimeSeriesDataButton
        function GenerateTimeSeriesDataButtonPushed(app, event)
            %%%%% REDUNDANT WITH PLOT DESIGN PUSHED... 3/1/24 %%%%
            epochs = app.EpochsEditField.Value;
            restPerEpoch = app.RestImagesEditField.Value;
            taskPerEpoch = app.TaskImagesEditField.Value;
            app.design = repmat([zeros(restPerEpoch,1);ones(taskPerEpoch,1)],epochs,1);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            disp(app.design)

            app.singlekSpaceButtonPushed()
            app.kSpaceTimeSeries = generateTimeSeries(app.kSpaceRest,app.kSpaceTask,app.EpochsEditField.Value,app.RestImagesEditField.Value,app.TaskImagesEditField.Value,app.sigma);
            app.imageSpaceTimeSeries = ifft2(fftshift(app.kSpaceTimeSeries));

            app.TimePointSpinner.Limits=[1,epochs*(restPerEpoch+taskPerEpoch)];
            app.xEditField.Limits = [1,app.MRI.ReconstructionSize];
            app.yEditField.Limits = [1,app.MRI.ReconstructionSize];
            app.TimePointSpinner.Enable = 'on';

            app.updateTimeSeriesPlot()
            app.TextOutput.Value = strjoin(['Generated time series data at:',string(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss'))]);
        end

        % Value changed function: NumberofCoilsSpinner
        function NumberofCoilsSpinnerValueChanged(app, event)
            value = app.NumberofCoilsSpinner.Value;
            app.CoilNumberSpinner.Limits = [1,value];
            app.CoilNumberSpinner.Enable = 'on';
            app.CoilNumberSpinner_sample.Limits = [1,value];
            app.CoilNumberSpinner_sample.Enable = 'on';
        end

        % Callback function: CoilNumberSpinner, DomainDropDown, 
        % ...and 3 other components
        function updateTimeSeriesPlot(app, event)
            voxelData = str2double(app.VoxelDataDropDown.Value);
            domain = app.DomainDropDown.Value;

            if app.design(app.TimePointSpinner.Value) == 1
                app.ActivationLamp.Color = [1,0,0];
            else
                app.ActivationLamp.Color = [0,1,0];
            end

            if domain == 1 % k space
                if voxelData == 1 % magnitude
                    imagesc(app.timeSeriesAxes,log10(abs(app.kSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value))+1))
                elseif voxelData == 2 % phase
                    imagesc(app.timeSeriesAxes,angle(app.kSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 3 % real
                    imagesc(app.timeSeriesAxes,real(app.kSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.timeSeriesAxes,imag(app.kSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 5 % coil avg
                    imagesc(app.timeSeriesAxes,log10(mean(abs(app.kSpaceTimeSeries(:,:,:,app.TimePointSpinner.Value))+1,3)))
                end
            elseif domain == 2 % image space
                if voxelData == 1 % magnitude
                    imagesc(app.timeSeriesAxes,abs(app.imageSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 2 % phase
                    imagesc(app.timeSeriesAxes,angle(app.imageSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 3 % real
                    imagesc(app.timeSeriesAxes,real(app.imageSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.timeSeriesAxes,imag(app.imageSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 5 % coil avg
                    imagesc(app.timeSeriesAxes,mean(abs(app.imageSpaceTimeSeries(:,:,:,app.TimePointSpinner.Value)),3))
                end
            end

            app.TextOutput.Value = 'changed view';
        end

        % Button pushed function: VoxeltimeSeriesButton
        function VoxeltimeSeriesButtonPushed(app, event)
            figure('Name','Voxel Time Series')
            coil_avgd = squeeze(mean(app.imageSpaceTimeSeries,3));
            realV = squeeze(real(coil_avgd(app.xEditField.Value,app.yEditField.Value,:)));
            imagV = squeeze(imag(coil_avgd(app.xEditField.Value,app.yEditField.Value,:)));
            time = 1:size(coil_avgd,3);
            plot3(time,realV,imagV)         
            title(['Voxel time series, x=',num2str(app.xEditField.Value),' y=',num2str(app.yEditField.Value)]);
            xlabel('time'); ylabel('real'); zlabel('imaginary');
        end

        % Drop down opening function: StatisticDropDown
        function StatisticDropDownOpening(app, event)
            statisticsFiles = dir(fullfile('statisticsFunctions/', '*.m'));
            app.StatisticDropDown.Items =  {statisticsFiles.name}';
        end

        % Button pushed function: statsButton
        function statsButtonPushed(app, event)
            statisticsFunction = app.StatisticDropDown.Value;
            disp(statisticsFunction(1:end-2))
            app.statisticsData = eval(strcat(statisticsFunction(1:end-2),'(app.imageSpaceTimeSeries,app.design)'));
            
            imagesc(app.statisticsAxes,app.statisticsData)
            colormap(app.statisticsAxes,"hot")
            colorbar(app.statisticsAxes)
        end

        % Callback function: ExportSingleKSpaceButton, singlekspace
        function ExportSingleKSpaceButtonPushed(app, event)
            ks = app.kSpace;
            uisave('ks','SinglekSpace.mat')
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Callback function: ExportTimeSeriesButton, TimeSerieskSpaceMenu
        function ExportTimeSeriesButtonPushed(app, event)
            ksTS = app.kSpaceTimeSeries;
            uisave('ksTS','kSpaceTimeSeries.mat')
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: ExportMRIStruct
        function ExportMRIStructMenuSelected(app, event)
            % update MRI structure
            updateMRIStruct(app);
            MRI = app.MRI;
            % export it
            uisave('MRI','MRI_Parameters.mat')
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Button pushed function: DiagnosticButton
        function DiagnosticButtonPushed(app, event)
            data = app.Phantom.M0;
            volshow(data(:,:,1:app.SliceSpinner.Value),RenderingStyle="CinematicRendering")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.MapsMenu = uimenu(app.ImportMenu);
            app.MapsMenu.Text = 'Maps';
            app.M0FileMenu = uimenu(app.MapsMenu);
            app.M0FileMenu.MenuSelectedFcn = createCallbackFcn(app, @M0FileMenuSelected, true);
            app.M0FileMenu.Text = 'M0 File';
            app.T1FileMenu = uimenu(app.MapsMenu);
            app.T1FileMenu.MenuSelectedFcn = createCallbackFcn(app, @T1FileMenuSelected, true);
            app.T1FileMenu.Text = 'T1 File';
            app.T2FileMenu = uimenu(app.MapsMenu);
            app.T2FileMenu.MenuSelectedFcn = createCallbackFcn(app, @T2FileMenuSelected, true);
            app.T2FileMenu.Text = 'T2 File';
            app.deltaBFileMenu = uimenu(app.MapsMenu);
            app.deltaBFileMenu.MenuSelectedFcn = createCallbackFcn(app, @deltaBFileMenuSelected, true);
            app.deltaBFileMenu.Text = 'delta B File';
            app.ActivationFileMenu = uimenu(app.MapsMenu);
            app.ActivationFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ActivationFileMenuSelected, true);
            app.ActivationFileMenu.Text = 'Activation File';
            app.ImportMRIStruct = uimenu(app.ImportMenu);
            app.ImportMRIStruct.MenuSelectedFcn = createCallbackFcn(app, @ImportMRIStructMenuSelected, true);
            app.ImportMRIStruct.Text = 'MRI Parameters';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.singlekspace = uimenu(app.ExportMenu);
            app.singlekspace.MenuSelectedFcn = createCallbackFcn(app, @ExportSingleKSpaceButtonPushed, true);
            app.singlekspace.Text = 'Single k-space';
            app.TimeSerieskSpaceMenu = uimenu(app.ExportMenu);
            app.TimeSerieskSpaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportTimeSeriesButtonPushed, true);
            app.TimeSerieskSpaceMenu.Text = 'Time Series k-Space';
            app.ExportMRIStruct = uimenu(app.ExportMenu);
            app.ExportMRIStruct.MenuSelectedFcn = createCallbackFcn(app, @ExportMRIStructMenuSelected, true);
            app.ExportMRIStruct.Text = 'MRI Parameters';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.MRIParametersTab = uitab(app.TabGroup);
            app.MRIParametersTab.Title = 'MRI Parameters';
            app.singlekSpaceAxes = uiaxes(app.MRIParametersTab);
            title(app.singlekSpaceAxes, 'Single k-Space View')
            app.singlekSpaceAxes.XTick = [];
            app.singlekSpaceAxes.YTick = [];
            app.singlekSpaceAxes.ButtonDownFcn = createCallbackFcn(app, @updateSingleKspacePlot, true);
            colormap(app.singlekSpaceAxes, 'gray')
            app.singlekSpaceButton = uibutton(app.MRIParametersTab, 'push');
            app.singlekSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @singlekSpaceButtonPushed, true);
            app.singlekSpaceButton.Text = 'Generate Single kSpace';
            app.ActivationCheckSingleKspace = uicheckbox(app.MRIParametersTab);
            app.ActivationCheckSingleKspace.ValueChangedFcn = createCallbackFcn(app, @updateSingleKspacePlot, true);
            app.ActivationCheckSingleKspace.Text = 'Show Activation';
            app.CoilNumberSpinner_2Label = uilabel(app.MRIParametersTab);
            app.CoilNumberSpinner_2Label.Text = 'Coil Number';
            app.CoilNumberSpinner_sample = uispinner(app.MRIParametersTab);
            app.CoilNumberSpinner_sample.Limits = [1 Inf];
            app.CoilNumberSpinner_sample.RoundFractionalValues = 'on';
            app.CoilNumberSpinner_sample.ValueChangedFcn = createCallbackFcn(app, @updateSingleKspacePlot, true);
            app.CoilNumberSpinner_sample.Enable = 'off';
            app.CoilNumberSpinner_sample.Value = 1;
            app.VoxelDataDropDown_sample = uidropdown(app.MRIParametersTab);
            app.VoxelDataDropDown_sample.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary', 'Coil avg (mag)'};
            app.VoxelDataDropDown_sample.ItemsData = {'1', '2', '3', '4', '5'};
            app.VoxelDataDropDown_sample.ValueChangedFcn = createCallbackFcn(app, @updateSingleKspacePlot, true);
            app.VoxelDataDropDown_sample.Value = '1';
            app.DomainDropDown_sample = uidropdown(app.MRIParametersTab);
            app.DomainDropDown_sample.Items = {'kSpace', 'Image Space', 'Coil Sensitivities'};
            app.DomainDropDown_sample.ItemsData = {'1', '2', '3'};
            app.DomainDropDown_sample.ValueChangedFcn = createCallbackFcn(app, @updateSingleKspacePlot, true);
            app.DomainDropDown_sample.Value = '1';
            app.SignalEquationDropdown = uidropdown(app.MRIParametersTab);
            app.SignalEquationDropdown.Items = {};
            app.SignalEquationDropdown.DropDownOpeningFcn = createCallbackFcn(app, @SignalEquationDropdownDropDownOpening, true);
            app.SignalEquationDropdown.ValueChangedFcn = createCallbackFcn(app, @SignalEquationDropdownValueChanged, true);
            app.SignalEquationDropdown.Placeholder = 'Select';
            app.SignalEquationDropdown.Value = {};
            app.ViewLabel_2 = uilabel(app.MRIParametersTab);
            app.ViewLabel_2.Text = 'Signal Equation';
            app.TrajectoryDropDown = uidropdown(app.MRIParametersTab);
            app.TrajectoryDropDown.Items = {};
            app.TrajectoryDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TrajectoryDropDownOpening, true);
            app.TrajectoryDropDown.Placeholder = 'Select';
            app.TrajectoryDropDown.Value = {};
            app.TrajectoryDropDownLabel = uilabel(app.MRIParametersTab);
            app.TrajectoryDropDownLabel.Text = 'Trajectory';
            app.AccelerationFactorSpinner = uispinner(app.MRIParametersTab);
            app.AccelerationFactorSpinner.Limits = [1 8];
            app.AccelerationFactorSpinner.Tooltip = {'Rate at which kspace data is subsampled'};
            app.AccelerationFactorSpinner.Value = 1;
            app.AccelerationFactorLabel = uilabel(app.MRIParametersTab);
            app.AccelerationFactorLabel.Text = 'Acceleration Factor';
            app.FieldStrengthTEditField = uieditfield(app.MRIParametersTab, 'numeric');
            app.FieldStrengthTEditField.Limits = [0 Inf];
            app.FieldStrengthTEditField.ValueChangedFcn = createCallbackFcn(app, @FieldStrengthTEditFieldValueChanged, true);
            app.FieldStrengthTEditField.Value = 3;
            app.FieldStrengthTEditFieldLabel = uilabel(app.MRIParametersTab);
            app.FieldStrengthTEditFieldLabel.Text = 'Field Strength (T)';
            app.TEmsEditField = uieditfield(app.MRIParametersTab, 'numeric');
            app.TEmsEditField.Limits = [0 Inf];
            app.TEmsEditField.Tooltip = {'Echo Time'};
            app.TEmsEditField.Value = 50;
            app.TEmsEditFieldLabel = uilabel(app.MRIParametersTab);
            app.TEmsEditFieldLabel.Text = 'TE (ms)';
            app.TRmsEditFieldLabel = uilabel(app.MRIParametersTab);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRmsEditField = uieditfield(app.MRIParametersTab, 'numeric');
            app.TRmsEditField.Limits = [0 Inf];
            app.TRmsEditField.Tooltip = {'Repitition Time'};
            app.TRmsEditField.Value = 1000;
            app.FlipAngledegEditField = uieditfield(app.MRIParametersTab, 'numeric');
            app.FlipAngledegEditField.Limits = [0 Inf];
            app.FlipAngledegEditField.Value = 90;
            app.FlipAngledegEditFieldLabel = uilabel(app.MRIParametersTab);
            app.FlipAngledegEditFieldLabel.Text = 'Flip Angle (deg)';
            app.NumberofCoilsSpinnerLabel = uilabel(app.MRIParametersTab);
            app.NumberofCoilsSpinnerLabel.Text = 'Number of Coils';
            app.NumberofCoilsSpinner = uispinner(app.MRIParametersTab);
            app.NumberofCoilsSpinner.Limits = [1 Inf];
            app.NumberofCoilsSpinner.RoundFractionalValues = 'on';
            app.NumberofCoilsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofCoilsSpinnerValueChanged, true);
            app.NumberofCoilsSpinner.Tooltip = {'Radially symmetric coil array'};
            app.NumberofCoilsSpinner.Value = 1;
            app.VoxelDataDropDown_2Label = uilabel(app.MRIParametersTab);
            app.VoxelDataDropDown_2Label.Text = 'Voxel Data';
            app.DomainDropDown_2Label = uilabel(app.MRIParametersTab);
            app.DomainDropDown_2Label.Text = 'Domain';
            app.ExportSingleKSpaceButton = uibutton(app.MRIParametersTab, 'push');
            app.ExportSingleKSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSingleKSpaceButtonPushed, true);
            app.ExportSingleKSpaceButton.Text = 'Export Single k-Space';
            app.M0T1msT2msLabel = uilabel(app.MRIParametersTab);
            app.M0T1msT2msLabel.Text = {'M0'; 'T1 (ms)'; 'T2* (ms)'};
            app.RelaxivityTable = uitable(app.MRIParametersTab);
            app.RelaxivityTable.ColumnName = {'GM'; 'WM'; 'CSF'};
            app.RelaxivityTable.RowName = {};
            app.RelaxivityTable.ColumnEditable = [true true true];
            app.RelaxivityTable.Tooltip = {'Gray Matter, White Matter, Cerebral Spinal Fluid'};
            app.deltaB0Check = uicheckbox(app.MRIParametersTab);
            app.deltaB0Check.Text = 'Include B0 Inhomogeneity';
            app.ReconstructionDropDown = uidropdown(app.MRIParametersTab);
            app.ReconstructionDropDown.Items = {};
            app.ReconstructionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ReconstructionDropDownOpening, true);
            app.ReconstructionDropDown.Placeholder = 'Select';
            app.ReconstructionDropDown.Value = {};
            app.ReconstructionDropDownLabel = uilabel(app.MRIParametersTab);
            app.ReconstructionDropDownLabel.Text = 'Reconstruction';
            app.TaskDesignTab = uitab(app.TabGroup);
            app.TaskDesignTab.Title = 'Task Design';
            app.HRFDropDownLabel = uilabel(app.TaskDesignTab);
            app.HRFDropDownLabel.Text = 'HRF';
            app.HRFDropDown = uidropdown(app.TaskDesignTab);
            app.HRFDropDown.Items = {'Block'};
            app.HRFDropDown.Tooltip = {'Haemodynamic Response Function'};
            app.HRFDropDown.Value = 'Block';
            app.EpochsEditFieldLabel = uilabel(app.TaskDesignTab);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.TaskDesignTab, 'numeric');
            app.EpochsEditField.Limits = [1 Inf];
            app.EpochsEditField.RoundFractionalValues = 'on';
            app.EpochsEditField.Tooltip = {'Repititions of task activation'};
            app.EpochsEditField.Value = 1;
            app.RestImagesEditFieldLabel = uilabel(app.TaskDesignTab);
            app.RestImagesEditFieldLabel.Text = 'Rest Images';
            app.RestImagesEditField = uieditfield(app.TaskDesignTab, 'numeric');
            app.RestImagesEditField.Limits = [0 Inf];
            app.RestImagesEditField.RoundFractionalValues = 'on';
            app.RestImagesEditField.Tooltip = {'per epoch'};
            app.RestImagesEditField.Value = 1;
            app.TaskImagesEditFieldLabel = uilabel(app.TaskDesignTab);
            app.TaskImagesEditFieldLabel.Text = 'Task Images';
            app.TaskImagesEditField = uieditfield(app.TaskDesignTab, 'numeric');
            app.TaskImagesEditField.Limits = [0 Inf];
            app.TaskImagesEditField.RoundFractionalValues = 'on';
            app.TaskImagesEditField.Tooltip = {'per epoch'};
            app.TaskImagesEditField.Value = 1;
            app.PlotDesignButton = uibutton(app.TaskDesignTab, 'push');
            app.PlotDesignButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDesignButtonPushed, true);
            app.PlotDesignButton.Text = 'Plot Design';
            app.GenerateTimeSeriesDataButton = uibutton(app.TaskDesignTab, 'push');
            app.GenerateTimeSeriesDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTimeSeriesDataButtonPushed, true);
            app.GenerateTimeSeriesDataButton.Text = 'Generate Time Series Data';
            app.TabGroup2 = uitabgroup(app.TaskDesignTab);
            app.Task1Tab = uitab(app.TabGroup2);
            app.Task1Tab.Title = 'Task 1';
            app.ActivationLabel = uilabel(app.Task1Tab);
            app.ActivationLabel.Text = 'Magnitude';
            app.ActivationMag = uieditfield(app.Task1Tab, 'numeric');
            app.ActivationMag.Tooltip = {'Given as percentage of the mximum voxel value in the non-task image'};
            app.ActivationMag.Value = 0.02;
            app.PhaseIncreaseLabel = uilabel(app.Task1Tab);
            app.PhaseIncreaseLabel.Text = 'Phase (deg)';
            app.ActivationPhase = uieditfield(app.Task1Tab, 'numeric');
            app.ActivationPhase.Tooltip = {'Angular increase of the phase in the activated ROI'};
            app.CNREditFieldLabel = uilabel(app.Task1Tab);
            app.CNREditFieldLabel.Text = 'CNR';
            app.CNREditField = uieditfield(app.Task1Tab, 'numeric');
            app.CNREditField.Limits = [0 Inf];
            app.CNREditField.Tooltip = {'Contrast-to-Noise Ratio, Beta1/sigma'};
            app.CNREditField.Value = 0.75;
            app.SNREditFieldLabel = uilabel(app.Task1Tab);
            app.SNREditFieldLabel.Enable = 'off';
            app.SNREditFieldLabel.Visible = 'off';
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.Task1Tab, 'numeric');
            app.SNREditField.Limits = [0 Inf];
            app.SNREditField.Editable = 'off';
            app.SNREditField.Enable = 'off';
            app.SNREditField.Visible = 'off';
            app.SNREditField.Tooltip = {'Signal-to-Noise Ratio, Beta0/sigma'};
            app.SNREditField.Value = 5;
            app.Task2Tab = uitab(app.TabGroup2);
            app.Task2Tab.Title = 'Task 2';
            app.OtherOptionsTab = uitab(app.TabGroup);
            app.OtherOptionsTab.Title = 'Other Options';
            app.tobefilledinsoon8142024Label = uilabel(app.OtherOptionsTab);
            app.tobefilledinsoon8142024Label.Text = 'to be filled in soon... 8/14/2024';
            app.DataViewerPanel = uipanel(app.LeftPanel);
            app.DataViewerPanel.Title = 'Data Viewer';
            app.MapAxes = uiaxes(app.DataViewerPanel);
            title(app.MapAxes, 'Data View')
            app.MapAxes.XTick = [];
            app.MapAxes.YTick = [];
            app.MapAxes.ButtonDownFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.PlotActivationCheck = uicheckbox(app.DataViewerPanel);
            app.PlotActivationCheck.ValueChangedFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.PlotActivationCheck.Text = 'Show Activation';
            app.SliceSpinner = uispinner(app.DataViewerPanel);
            app.SliceSpinner.Limits = [1 96];
            app.SliceSpinner.RoundFractionalValues = 'on';
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.SliceSpinner.Value = 48;
            app.SliceLabel = uilabel(app.DataViewerPanel);
            app.SliceLabel.Text = 'Slice';
            app.SliceViewDropDown = uidropdown(app.DataViewerPanel);
            app.SliceViewDropDown.Items = {'Axial', 'Sagital', 'Coronal'};
            app.SliceViewDropDown.ItemsData = {'1', '2', '3'};
            app.SliceViewDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.SliceViewDropDown.Value = '1';
            app.ViewLabel = uilabel(app.DataViewerPanel);
            app.ViewLabel.Text = 'View';
            app.MapDropDownLabel = uilabel(app.DataViewerPanel);
            app.MapDropDownLabel.Text = 'Map';
            app.MapDropDown = uidropdown(app.DataViewerPanel);
            app.MapDropDown.Items = {'M0', 'T1', 'T2*', 'delta B'};
            app.MapDropDown.ItemsData = [1 2 3 4];
            app.MapDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.MapDropDown.Value = 1;
            app.DViewButton = uibutton(app.DataViewerPanel, 'push');
            app.DViewButton.ButtonPushedFcn = createCallbackFcn(app, @threeDViewButtonPushed, true);
            app.DViewButton.Text = '3D View';
            app.DataUpscaleEditFieldLabel = uilabel(app.DataViewerPanel);
            app.DataUpscaleEditFieldLabel.Text = 'Data Upscale';
            app.DataUpscaleEditField = uieditfield(app.DataViewerPanel, 'numeric');
            app.DataUpscaleEditField.Limits = [48 1024];
            app.DataUpscaleEditField.Value = 96;
            app.CenterPanel = uipanel(app.GridLayout);
            app.statisticsAxes = uiaxes(app.CenterPanel);
            title(app.statisticsAxes, 'Statistics View')
            app.statisticsAxes.XTick = [];
            app.statisticsAxes.YTick = [];
            colormap(app.statisticsAxes, 'gray')
            app.StatsVoxelDataDropDown = uidropdown(app.CenterPanel);
            app.StatsVoxelDataDropDown.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary', 'Coil Avg (mag)'};
            app.StatsVoxelDataDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.StatsVoxelDataDropDown.Value = '1';
            app.StatsDomainDropDown = uidropdown(app.CenterPanel);
            app.StatsDomainDropDown.Items = {'kSpace', 'Image Space'};
            app.StatsDomainDropDown.ItemsData = [1 2];
            app.StatsDomainDropDown.Value = 1;
            app.statsButton = uibutton(app.CenterPanel, 'push');
            app.statsButton.ButtonPushedFcn = createCallbackFcn(app, @statsButtonPushed, true);
            app.statsButton.Tooltip = {'View t-statistic map of mean activation v mean rest images'};
            app.statsButton.Text = 'Calculate Statistic';
            app.StatisticDropDownLabel = uilabel(app.CenterPanel);
            app.StatisticDropDownLabel.Text = 'Statistic';
            app.StatisticDropDown = uidropdown(app.CenterPanel);
            app.StatisticDropDown.Items = {};
            app.StatisticDropDown.DropDownOpeningFcn = createCallbackFcn(app, @StatisticDropDownOpening, true);
            app.StatisticDropDown.Placeholder = 'Select';
            app.StatisticDropDown.Value = {};
            app.DiagnosticButton = uibutton(app.CenterPanel, 'push');
            app.DiagnosticButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticButtonPushed, true);
            app.DiagnosticButton.Text = 'Diagnostic Button';
            app.TimeSeriesPanel = uipanel(app.CenterPanel);
            app.TimeSeriesPanel.Title = 'Time Series Panel';
            app.timeSeriesAxes = uiaxes(app.TimeSeriesPanel);
            title(app.timeSeriesAxes, 'Time Series View')
            app.timeSeriesAxes.XTick = [];
            app.timeSeriesAxes.YTick = [];
            app.timeSeriesAxes.ButtonDownFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            colormap(app.timeSeriesAxes, 'gray')
            app.VoxeltimeSeriesButton = uibutton(app.TimeSeriesPanel, 'push');
            app.VoxeltimeSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @VoxeltimeSeriesButtonPushed, true);
            app.VoxeltimeSeriesButton.Text = 'Voxel time Series';
            app.ExportTimeSeriesButton = uibutton(app.TimeSeriesPanel, 'push');
            app.ExportTimeSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTimeSeriesButtonPushed, true);
            app.ExportTimeSeriesButton.Text = 'Export Time Series';
            app.TimePointSpinnerLabel = uilabel(app.TimeSeriesPanel);
            app.TimePointSpinnerLabel.Text = 'Time Point';
            app.TimePointSpinner = uispinner(app.TimeSeriesPanel);
            app.TimePointSpinner.Limits = [1 Inf];
            app.TimePointSpinner.RoundFractionalValues = 'on';
            app.TimePointSpinner.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.TimePointSpinner.Enable = 'off';
            app.TimePointSpinner.Value = 1;
            app.CoilNumberSpinnerLabel = uilabel(app.TimeSeriesPanel);
            app.CoilNumberSpinnerLabel.Text = 'Coil Number';
            app.CoilNumberSpinner = uispinner(app.TimeSeriesPanel);
            app.CoilNumberSpinner.Limits = [1 Inf];
            app.CoilNumberSpinner.RoundFractionalValues = 'on';
            app.CoilNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.CoilNumberSpinner.Enable = 'off';
            app.CoilNumberSpinner.Value = 1;
            app.DomainDropDownLabel = uilabel(app.TimeSeriesPanel);
            app.DomainDropDownLabel.Text = 'Domain';
            app.DomainDropDown = uidropdown(app.TimeSeriesPanel);
            app.DomainDropDown.Items = {'kSpace', 'Image Space'};
            app.DomainDropDown.ItemsData = [1 2];
            app.DomainDropDown.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.DomainDropDown.Value = 1;
            app.VoxelDataDropDownLabel = uilabel(app.TimeSeriesPanel);
            app.VoxelDataDropDownLabel.Text = 'Voxel Data';
            app.VoxelDataDropDown = uidropdown(app.TimeSeriesPanel);
            app.VoxelDataDropDown.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary', 'Coil Avg (mag)'};
            app.VoxelDataDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.VoxelDataDropDown.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.VoxelDataDropDown.Value = '1';
            app.ActivationLamp = uilamp(app.TimeSeriesPanel);
            app.ActivationLamp.Tooltip = {'Red if in rest state, grreen if in task state'};
            app.ActivationLabel_2 = uilabel(app.TimeSeriesPanel);
            app.ActivationLabel_2.Text = 'Activation';
            app.xEditFieldLabel = uilabel(app.TimeSeriesPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.TimeSeriesPanel, 'numeric');
            app.xEditField.Limits = [1 Inf];
            app.xEditField.RoundFractionalValues = 'on';
            app.xEditField.Value = 55;
            app.yEditFieldLabel = uilabel(app.TimeSeriesPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.TimeSeriesPanel, 'numeric');
            app.yEditField.Limits = [1 Inf];
            app.yEditField.RoundFractionalValues = 'on';
            app.yEditField.Value = 35;
            app.DomainDropDown_2Label_2 = uilabel(app.CenterPanel);
            app.DomainDropDown_2Label_2.Text = 'Domain';
            app.VoxelDataDropDown_2Label_2 = uilabel(app.CenterPanel);
            app.VoxelDataDropDown_2Label_2.Text = 'Voxel Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.TextOutput = uitextarea(app.GridLayout2);
            app.TextOutput.Editable = 'off';
            app.TextOutput.Value = {'SHAKR'; 'Simulation and Harmonic Analysis of K-space Readout'};
            app.Hyperlink = uihyperlink(app.GridLayout2);
            app.Hyperlink.URL = 'https://github.com/bodensjc/kspace-simulator';
            app.Hyperlink.Text = 'GitHub';
            app.SHAKRMarquetteUniversityBodenschatzRowe2024Label = uilabel(app.GridLayout2);
            app.SHAKRMarquetteUniversityBodenschatzRowe2024Label.Tooltip = {'Simulation and Harmonic Analysis of K-spacE Readout'};
            app.SHAKRMarquetteUniversityBodenschatzRowe2024Label.Text = {'SHAKR'; 'Marquette University'; 'Bodenschatz, Rowe, 2024'};
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'data', 'Marquette_mu_small.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ksim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end